classdef CurrentGraphForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Label        matlab.ui.control.Label
        ButtonGroup  matlab.ui.container.ButtonGroup
        Button_4     matlab.ui.control.ToggleButton
        Button_3     matlab.ui.control.ToggleButton
        Button_2     matlab.ui.control.ToggleButton
        Button       matlab.ui.control.ToggleButton
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainForm % Description
    end
    

    methods (Access = public)

        function myplot(app,y)
            x = 0:0.25:23.75;
            plot(app.UIAxes,x,y);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainform)
            app.mainForm = mainform;
            app.myplot(app.mainForm.mathCode.machine1);
        end

        % Callback function
        function helloBtn(app, event)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %app.mainForm.CurrentGraphBtn.Enable = 'on';
            app.mainForm.Switch_5.Enable = "on";

            delete(app)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "机组一"
                    if app.mainForm.Switch.Value == "On"
                        app.myplot(app.mainForm.mathCode.machine1);
                    end
                case "机组二"
                    if app.mainForm.Switch_2.Value == "On"
                        app.myplot(app.mainForm.mathCode.machine2);
                    end
                    if app.mainForm.Switch_2.Value == "Off" && app.mainForm.mathCode.windmax ~= 0
                        app.myplot(app.mainForm.mathCode.wd);
                    end
                case "机组三"
                    if app.mainForm.Switch_3.Value == "On"
                        app.myplot(app.mainForm.mathCode.machine3);
                    end
                    if app.mainForm.Switch_3.Value == "Off" && app.mainForm.mathCode.windmax ~= 0
                        app.myplot(app.mainForm.mathCode.wd);
                    end
                case "总览"
                    t = 0:0.25:23.75;
                    if app.mainForm.mathCode.windmax == 0
                        plot(app.UIAxes,t,app.mainForm.mathCode.machine3,t,app.mainForm.mathCode.machine2,t,app.mainForm.mathCode.machine1);
                    end
                    if app.mainForm.Switch_2.Value == "Off" && app.mainForm.mathCode.windmax ~= 0 && app.mainForm.Switch_3.Value == "On" && app.mainForm.Switch.Value == "On"
                        plot(app.UIAxes,t,app.mainForm.mathCode.machine3,t,app.mainForm.mathCode.wd,t,app.mainForm.mathCode.machine1);
                    end
                    if app.mainForm.Switch_3.Value == "Off" && app.mainForm.mathCode.windmax ~= 0 && app.mainForm.Switch_2.Value == "On" && app.mainForm.Switch.Value == "On"
                        plot(app.UIAxes,t,app.mainForm.mathCode.machine2,t,app.mainForm.mathCode.wd,t,app.mainForm.mathCode.machine1);
                    end
                    if app.mainForm.Switch_3.Value == "Off" && app.mainForm.mathCode.windmax ~= 0 && app.mainForm.Switch_2.Value == "Off" && app.mainForm.Switch.Value == "On"
                        plot(app.UIAxes,t,app.mainForm.mathCode.wd,t,app.mainForm.mathCode.machine1);
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '机组一天的发电量曲线')
            xlabel(app.UIAxes, '时间/h')
            ylabel(app.UIAxes, '发电量/MW')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 24];
            app.UIAxes.YLim = [0 650];
            app.UIAxes.XTick = [0 2 4 6 8 10 12 14 16 18 20 22 24];
            app.UIAxes.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'; '18'; '20'; '22'; '24'};
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '请选择机组';
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = '机组一';
            app.Button.Value = true;
            app.Button_2 = uitogglebutton(app.ButtonGroup);
            app.Button_2.Text = '机组二';
            app.Button_3 = uitogglebutton(app.ButtonGroup);
            app.Button_3.Text = '机组三';
            app.Button_4 = uitogglebutton(app.ButtonGroup);
            app.Button_4.Text = '总览';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '机组一天的发电量曲线';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CurrentGraphForm(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end