classdef HuoDianJiZuSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Label_2                  matlab.ui.control.Label
        NoBtn                    matlab.ui.control.Button
        YesBtn                   matlab.ui.control.Button
        Panel_3                  matlab.ui.container.Panel
        akgMW2hEditField_3       matlab.ui.control.NumericEditField
        akgMW2hEditField_3Label  matlab.ui.control.Label
        bkgMWhEditField_3        matlab.ui.control.NumericEditField
        bkgMWhEditField_3Label   matlab.ui.control.Label
        ckghEditField_3          matlab.ui.control.NumericEditField
        ckghEditField_3Label     matlab.ui.control.Label
        kgkWhEditField_3         matlab.ui.control.NumericEditField
        kgkWhEditField_3Label    matlab.ui.control.Label
        putout_min_3             matlab.ui.control.NumericEditField
        MWEditField_6Label       matlab.ui.control.Label
        putout_max_3             matlab.ui.control.NumericEditField
        MWEditField_5Label       matlab.ui.control.Label
        Panel_2                  matlab.ui.container.Panel
        akgMW2hEditField_2       matlab.ui.control.NumericEditField
        akgMW2hEditField_2Label  matlab.ui.control.Label
        bkgMWhEditField_2        matlab.ui.control.NumericEditField
        bkgMWhEditField_2Label   matlab.ui.control.Label
        ckghEditField_2          matlab.ui.control.NumericEditField
        ckghEditField_2Label     matlab.ui.control.Label
        kgkWhEditField_2         matlab.ui.control.NumericEditField
        kgkWhEditField_2Label    matlab.ui.control.Label
        putout_min_2             matlab.ui.control.NumericEditField
        MWEditField_4Label       matlab.ui.control.Label
        putout_max_2             matlab.ui.control.NumericEditField
        MWEditField_3Label       matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        akgMW2hEditField         matlab.ui.control.NumericEditField
        akgMW2hEditFieldLabel    matlab.ui.control.Label
        bkgMWhEditField          matlab.ui.control.NumericEditField
        bkgMWhEditFieldLabel     matlab.ui.control.Label
        ckghEditField            matlab.ui.control.NumericEditField
        ckghEditFieldLabel       matlab.ui.control.Label
        kgkWhEditField           matlab.ui.control.NumericEditField
        kgkWhEditFieldLabel      matlab.ui.control.Label
        putout_min               matlab.ui.control.NumericEditField
        MWEditField_2Label       matlab.ui.control.Label
        putout_max               matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
    end


    properties (Access = private)
        mainForm % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainform)
            app.mainForm = mainform;

            app.putout_max.Value = mainform.mathCode.mx1;
            app.putout_max_2.Value = mainform.mathCode.mx2;
            app.putout_max_3.Value = mainform.mathCode.mx3;

            app.putout_min.Value = mainform.mathCode.mn1;
            app.putout_min_2.Value = mainform.mathCode.mn2;
            app.putout_min_3.Value = mainform.mathCode.mn3;

            app.kgkWhEditField.Value = mainform.mathCode.ce1;
            app.kgkWhEditField_2.Value = mainform.mathCode.ce2;
            app.kgkWhEditField_3.Value = mainform.mathCode.ce3;

            app.akgMW2hEditField.Value = mainform.mathCode.ca1;
            app.akgMW2hEditField_2.Value = mainform.mathCode.ca2;
            app.akgMW2hEditField_3.Value = mainform.mathCode.ca3;

            app.bkgMWhEditField.Value = mainform.mathCode.cb1;
            app.bkgMWhEditField_2.Value = mainform.mathCode.cb2;
            app.bkgMWhEditField_3.Value = mainform.mathCode.cb3;

            app.ckghEditField.Value = mainform.mathCode.cc1 ;
            app.ckghEditField_2.Value = mainform.mathCode.cc2 ;
            app.ckghEditField_3.Value = mainform.mathCode.cc3 ;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainForm.HuoDianJiZu_settings.Enable = 'on';

            delete(app)
        end

        % Button pushed function: YesBtn
        function YesBtnPushed(app, event)
            app.mainForm.mathCode.mx1 = app.putout_max.Value;
            app.mainForm.mathCode.mx2 = app.putout_max_2.Value;
            app.mainForm.mathCode.mx3 = app.putout_max_3.Value;

            app.mainForm.mathCode.mn1 = app.putout_min.Value;
            app.mainForm.mathCode.mn2 = app.putout_min_2.Value;
            app.mainForm.mathCode.mn3 = app.putout_min_3.Value;

            app.mainForm.mathCode.ce1 = app.kgkWhEditField.Value;
            app.mainForm.mathCode.ce2 = app.kgkWhEditField_2.Value;
            app.mainForm.mathCode.ce3 = app.kgkWhEditField_3.Value;

            app.mainForm.mathCode.ca1 = app.akgMW2hEditField.Value;
            app.mainForm.mathCode.ca2 = app.akgMW2hEditField_2.Value;
            app.mainForm.mathCode.ca3 = app.akgMW2hEditField_3.Value;

            app.mainForm.mathCode.cb1 = app.bkgMWhEditField.Value;
            app.mainForm.mathCode.cb2 = app.bkgMWhEditField_2.Value;
            app.mainForm.mathCode.cb3 = app.bkgMWhEditField_3.Value;

            app.mainForm.mathCode.cc1 = app.ckghEditField.Value;
            app.mainForm.mathCode.cc2 = app.ckghEditField_2.Value;
            app.mainForm.mathCode.cc3 = app.ckghEditField_3.Value;

            app.mainForm.HuoDianJiZu_settings.Enable = 'on';

            delete(app)
        end

        % Button pushed function: NoBtn
        function NoBtnPushed(app, event)
            app.mainForm.HuoDianJiZu_settings.Enable = 'on';

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '火电机组1参数设置：';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '机组最大技术出力(MW)';
            app.putout_max = uieditfield(app.Panel, 'numeric');
            app.MWEditField_2Label = uilabel(app.Panel);
            app.MWEditField_2Label.Text = '机组最小技术出力(MW)';
            app.putout_min = uieditfield(app.Panel, 'numeric');
            app.kgkWhEditFieldLabel = uilabel(app.Panel);
            app.kgkWhEditFieldLabel.Text = '碳排放量(kg/kW·h)';
            app.kgkWhEditField = uieditfield(app.Panel, 'numeric');
            app.ckghEditFieldLabel = uilabel(app.Panel);
            app.ckghEditFieldLabel.Text = '煤耗量系数c/(kg/h)';
            app.ckghEditField = uieditfield(app.Panel, 'numeric');
            app.bkgMWhEditFieldLabel = uilabel(app.Panel);
            app.bkgMWhEditFieldLabel.Text = '煤耗量系数b/(kg·(MW/h))';
            app.bkgMWhEditField = uieditfield(app.Panel, 'numeric');
            app.akgMW2hEditFieldLabel = uilabel(app.Panel);
            app.akgMW2hEditFieldLabel.Text = '煤耗量系数a/(kg·(MW^2/h))';
            app.akgMW2hEditField = uieditfield(app.Panel, 'numeric');
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '火电机组2参数设置：';
            app.MWEditField_3Label = uilabel(app.Panel_2);
            app.MWEditField_3Label.Text = '机组最大技术出力(MW)';
            app.putout_max_2 = uieditfield(app.Panel_2, 'numeric');
            app.MWEditField_4Label = uilabel(app.Panel_2);
            app.MWEditField_4Label.Text = '机组最小技术出力(MW)';
            app.putout_min_2 = uieditfield(app.Panel_2, 'numeric');
            app.kgkWhEditField_2Label = uilabel(app.Panel_2);
            app.kgkWhEditField_2Label.Text = '碳排放量(kg/kW·h)';
            app.kgkWhEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.ckghEditField_2Label = uilabel(app.Panel_2);
            app.ckghEditField_2Label.Text = '煤耗量系数c/(kg/h)';
            app.ckghEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.bkgMWhEditField_2Label = uilabel(app.Panel_2);
            app.bkgMWhEditField_2Label.Text = '煤耗量系数b/(kg·(MW/h))';
            app.bkgMWhEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.akgMW2hEditField_2Label = uilabel(app.Panel_2);
            app.akgMW2hEditField_2Label.Text = '煤耗量系数a/(kg·(MW^2/h))';
            app.akgMW2hEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '火电机组3参数设置：';
            app.MWEditField_5Label = uilabel(app.Panel_3);
            app.MWEditField_5Label.Text = '机组最大技术出力(MW)';
            app.putout_max_3 = uieditfield(app.Panel_3, 'numeric');
            app.MWEditField_6Label = uilabel(app.Panel_3);
            app.MWEditField_6Label.Text = '机组最小技术出力(MW)';
            app.putout_min_3 = uieditfield(app.Panel_3, 'numeric');
            app.kgkWhEditField_3Label = uilabel(app.Panel_3);
            app.kgkWhEditField_3Label.Text = '碳排放量(kg/kW·h)';
            app.kgkWhEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.ckghEditField_3Label = uilabel(app.Panel_3);
            app.ckghEditField_3Label.Text = '煤耗量系数c/(kg/h)';
            app.ckghEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.bkgMWhEditField_3Label = uilabel(app.Panel_3);
            app.bkgMWhEditField_3Label.Text = '煤耗量系数b/(kg·(MW/h))';
            app.bkgMWhEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.akgMW2hEditField_3Label = uilabel(app.Panel_3);
            app.akgMW2hEditField_3Label.Text = '煤耗量系数a/(kg·(MW^2/h))';
            app.akgMW2hEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.YesBtn = uibutton(app.UIFigure, 'push');
            app.YesBtn.ButtonPushedFcn = createCallbackFcn(app, @YesBtnPushed, true);
            app.YesBtn.Text = '确认';
            app.NoBtn = uibutton(app.UIFigure, 'push');
            app.NoBtn.ButtonPushedFcn = createCallbackFcn(app, @NoBtnPushed, true);
            app.NoBtn.Text = '取消';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '火电机组参数设置';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HuoDianJiZuSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end