classdef MainForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image                       matlab.ui.control.Image
        TanBuJi_settings_label      matlab.ui.control.Label
        TanBuJi_settings            matlab.ui.control.Button
        Label_6                     matlab.ui.control.Label
        HuoDianJiZu_settings_label  matlab.ui.control.Label
        HuoDianJiZu_settings        matlab.ui.control.Button
        ChuNeng_lamp                matlab.ui.control.Lamp
        HuoJi3_lamp                 matlab.ui.control.Lamp
        HuoJi2_lamp                 matlab.ui.control.Lamp
        HuoJi1_lamp                 matlab.ui.control.Lamp
        Lamp                        matlab.ui.control.Lamp
        Label_5                     matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        Switch_5                    matlab.ui.control.RockerSwitch
        Label_4                     matlab.ui.control.Label
        Switch_4                    matlab.ui.control.Switch
        Label_3                     matlab.ui.control.Label
        DatePicker                  matlab.ui.control.DatePicker
        Label_2                     matlab.ui.control.Label
        Switch_3                    matlab.ui.control.ToggleSwitch
        Switch_3Label               matlab.ui.control.Label
        Switch_2                    matlab.ui.control.ToggleSwitch
        Switch_2Label               matlab.ui.control.Label
        Switch                      matlab.ui.control.ToggleSwitch
        Label                       matlab.ui.control.Label
        ButtonGroup_2               matlab.ui.container.ButtonGroup
        tButton_4                   matlab.ui.control.ToggleButton
        tButton_3                   matlab.ui.control.ToggleButton
        tButton_2                   matlab.ui.control.ToggleButton
        tButton                     matlab.ui.control.ToggleButton
        ButtonGroup                 matlab.ui.container.ButtonGroup
        MWButton_4                  matlab.ui.control.ToggleButton
        MWButton_3                  matlab.ui.control.ToggleButton
        MWButton_2                  matlab.ui.control.ToggleButton
        MWButton                    matlab.ui.control.ToggleButton
    end


    properties (Access = private)
        currentGraph % Description
        datatable = "预测数据表1.xlsx" % Description
        huoDianJiZu_settings % Description
        fengDianJiZu_settings % Description
        chuNeng_settings % Description
        tanBuJi_settings % Description
    end

    properties (Access = public)
        mathCode% Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mathCode = MathCode(1,1,1,0,0,1,0);
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            value = app.Switch_5.Value;

            valueDP = app.DatePicker.Value;
            switch valueDP
                case "2022-09-01"
                    app.mathCode.rowOfData2 = 1; app.datatable = "预测数据表2.xlsx";
                case "2022-09-02"
                    app.mathCode.rowOfData2 = 96+1; app.datatable = "预测数据表2.xlsx";
                case "2022-09-03"
                    app.mathCode.rowOfData2 = 96*2+1; app.datatable = "预测数据表2.xlsx";
                case "2022-09-04"
                    app.mathCode.rowOfData2 = 96*3+1; app.datatable = "预测数据表2.xlsx";
                case "2022-09-05"
                    app.mathCode.rowOfData2 = 96*4+1; app.datatable = "预测数据表2.xlsx";
                case "2022-09-06"
                    app.mathCode.rowOfData2 = 96*5+1; app.datatable = "预测数据表2.xlsx";
                case "2022-09-07"
                    app.mathCode.rowOfData2 = 96*6+1; app.datatable = "预测数据表2.xlsx";
                case "2022-09-08"
                    app.mathCode.rowOfData2 = 96*7+1; app.datatable = "预测数据表2.xlsx";
                case "2022-09-09"
                    app.mathCode.rowOfData2 = 96*8+1; app.datatable = "预测数据表2.xlsx";
                case "2022-09-10"
                    app.mathCode.rowOfData2 = 96*9+1; app.datatable = "预测数据表2.xlsx";
                case "2022-09-11"
                    app.mathCode.rowOfData2 = 96*10+1; app.datatable = "预测数据表2.xlsx";
                case "2022-09-12"
                    app.mathCode.rowOfData2 = 96*11+1; app.datatable = "预测数据表2.xlsx";
                case "2022-09-13"
                    app.mathCode.rowOfData2 = 96*12+1; app.datatable = "预测数据表2.xlsx";
                case "2022-09-14"
                    app.mathCode.rowOfData2 = 96*13+1; app.datatable = "预测数据表2.xlsx";
                case "2022-09-15"
                    app.mathCode.rowOfData2 = 96*14+1; app.datatable = "预测数据表2.xlsx";
                otherwise
                    app.datatable = "预测数据表1.xlsx";
            end

            try
                app.mathCode.GetData(app.datatable);
            catch
                app.Lamp.Color = [1 0 0];   %计算失败显示红灯
            end

            if value == "On"
                try
                    app.mathCode.f4();
                    app.UITable.Data = [app.mathCode.totalcost,app.mathCode.abwindcost,app.mathCode.loadcost,...
                        app.mathCode.carboncost,app.mathCode.storecost,app.mathCode.windcost,...
                        app.mathCode.firecost,app.mathCode.unitprive];

                    app.Switch_5.Enable = "off";
                    app.currentGraph = CurrentGraphForm(app);
                    app.Lamp.Color = [0 1 0];   %计算成功显示绿灯
                catch
                    app.Lamp.Color = [1 0 0];   %计算失败显示红灯
                end
            end

            if value == "Off"   %计算开关关闭，成本显示清空
                app.Lamp.Color = [0 0 1];   %未计算时显示蓝灯
                app.datatable = "";
                app.mathCode.data = [];
                app.UITable.Data = [];
            end


        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Off"
                app.mathCode.unit1 = 0;
                app.HuoJi1_lamp.Color = [1 0 0];    %关闭显示红灯
            elseif value == "On"
                app.mathCode.unit1 = 1;
                app.HuoJi1_lamp.Color = [0 1 0];    %打开显示绿灯
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if value == "Off"
                app.mathCode.unit2 = 0;
                app.HuoJi2_lamp.Color = [1 0 0];
            elseif value == "On"
                app.mathCode.unit2 = 1;
                app.HuoJi2_lamp.Color = [0 1 0];
            end
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            if value == "Off"
                app.mathCode.unit3 = 0;
                app.HuoJi3_lamp.Color = [1 0 0];
            elseif value == "On"
                app.mathCode.unit3 = 1;
                app.HuoJi3_lamp.Color = [0 1 0];
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.huoDianJiZu_settings)
            delete(app.fengDianJiZu_settings)
            delete(app.chuNeng_settings)
            delete(app.currentGraph)
            delete(app)

        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            if value == "Off"
                app.ChuNeng_lamp.Color = [1 0 0];
                app.mathCode.store = 0;
                app.mathCode.store1 = 1;
            end
            if value == "On"
                app.ChuNeng_lamp.Color = [0 1 0];
                app.mathCode.store = 1;
                app.mathCode.store1 = 0;
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            switch selectedButton.Text
                case "0元/t"
                    app.mathCode.carbon = 0;
                case "60元/t"
                    app.mathCode.carbon = 60;
                case "80元/t"
                    app.mathCode.carbon = 80;
                case "100元/t"
                    app.mathCode.carbon = 100;
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "0MW"
                    app.mathCode.windmax = 0;
                case "300MW"
                    app.mathCode.windmax = 300;
                case "600MW"
                    app.mathCode.windmax = 600;
                case "900MW"
                    app.mathCode.windmax = 900;
            end
        end

        % Value changed function: DatePicker
        function DatePickerValueChanged(app, event)
            value = app.DatePicker.Value;
        end

        % Button pushed function: HuoDianJiZu_settings
        function HuoDianJiZu_settingsButtonPushed(app, event)
            app.HuoDianJiZu_settings.Enable = "off";
            
            app.huoDianJiZu_settings = HuoDianJiZuSettings(app);
        end

        % Callback function
        function ChuNeng_settingsButtonPushed(app, event)
            app.ChuNeng_settings.Enable = "off";
            
            app.chuNeng_settings = ChuNengSettings(app);
        end

        % Callback function
        function FengDianJiZu_settingsButtonPushed(app, event)
            app.FengDianJiZu_settings.Enable = "off";
            
            app.fengDianJiZu_settings = FengDianJiZuSettings(app);
        end

        % Button pushed function: TanBuJi_settings
        function TanBuJi_settingsButtonPushed(app, event)
            app.TanBuJi_settings.Enable = "off";
            
            app.tanBuJi_settings = TanBuJiSettings(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '风电最大装机容量选择';
            app.MWButton = uitogglebutton(app.ButtonGroup);
            app.MWButton.Text = '0MW';
            app.MWButton.Value = true;
            app.MWButton_2 = uitogglebutton(app.ButtonGroup);
            app.MWButton_2.Text = '300MW';
            app.MWButton_3 = uitogglebutton(app.ButtonGroup);
            app.MWButton_3.Text = '600MW';
            app.MWButton_4 = uitogglebutton(app.ButtonGroup);
            app.MWButton_4.Text = '900MW';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = '碳捕集成本';
            app.tButton = uitogglebutton(app.ButtonGroup_2);
            app.tButton.Text = '0元/t';
            app.tButton.Value = true;
            app.tButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.tButton_2.Text = '60元/t';
            app.tButton_3 = uitogglebutton(app.ButtonGroup_2);
            app.tButton_3.Text = '80元/t';
            app.tButton_4 = uitogglebutton(app.ButtonGroup_2);
            app.tButton_4.Text = '100元/t';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '火电机组1';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'On';
            app.Switch_2Label = uilabel(app.UIFigure);
            app.Switch_2Label.Text = '火电机组2';
            app.Switch_2 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'On';
            app.Switch_3Label = uilabel(app.UIFigure);
            app.Switch_3Label.Text = '火电机组3';
            app.Switch_3 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'On';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '选择预测数据';
            app.DatePicker = uidatepicker(app.UIFigure);
            app.DatePicker.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '储能';
            app.Switch_4 = uiswitch(app.UIFigure, 'slider');
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '开始计算';
            app.Switch_5 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'总成本/元'; '弃风损失/元'; '失负荷损失/元'; '碳捕集成本/元'; '储能成本/元'; '风电成本/元'; '火电成本/元'; '单价/元/kWh'};
            app.UITable.RowName = {};
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '计算完成';
            app.Lamp = uilamp(app.UIFigure);
            app.HuoJi1_lamp = uilamp(app.UIFigure);
            app.HuoJi2_lamp = uilamp(app.UIFigure);
            app.HuoJi3_lamp = uilamp(app.UIFigure);
            app.ChuNeng_lamp = uilamp(app.UIFigure);
            app.HuoDianJiZu_settings = uibutton(app.UIFigure, 'push');
            app.HuoDianJiZu_settings.ButtonPushedFcn = createCallbackFcn(app, @HuoDianJiZu_settingsButtonPushed, true);
            app.HuoDianJiZu_settings.Icon = fullfile(pathToMLAPP, 'R-C.jpg');
            app.HuoDianJiZu_settings.Text = '';
            app.HuoDianJiZu_settings_label = uilabel(app.UIFigure);
            app.HuoDianJiZu_settings_label.Text = '火电机组参数设置';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '高比例风电并网发电成本辅助计算工具';
            app.TanBuJi_settings = uibutton(app.UIFigure, 'push');
            app.TanBuJi_settings.ButtonPushedFcn = createCallbackFcn(app, @TanBuJi_settingsButtonPushed, true);
            app.TanBuJi_settings.Icon = fullfile(pathToMLAPP, 'R-C.jpg');
            app.TanBuJi_settings.Text = '';
            app.TanBuJi_settings_label = uilabel(app.UIFigure);
            app.TanBuJi_settings_label.Text = '碳捕集成本设置';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '背景.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end