classdef TanBuJiSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Label_2        matlab.ui.control.Label
        NoBtn          matlab.ui.control.Button
        YesBtn         matlab.ui.control.Button
        Unit_label     matlab.ui.control.Label
        TanBuJiDanJia  matlab.ui.control.EditField
        Label          matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainForm % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainform)
            app.mainForm = mainform;
            app.TanBuJiDanJia.Value = num2str(mainform.mathCode.carbon);
        end

        % Button pushed function: YesBtn
        function YesBtnButtonPushed(app, event)
            app.mainForm.TanBuJi_settings.Enable = 'on';
            app.mainForm.mathCode.carbon = str2double(app.TanBuJiDanJia.Value);
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainForm.TanBuJi_settings.Enable = 'on';

            delete(app)
        end

        % Button pushed function: NoBtn
        function NoBtnButtonPushed(app, event)
            app.mainForm.TanBuJi_settings.Enable = 'on';

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '碳捕集单价';
            app.TanBuJiDanJia = uieditfield(app.UIFigure, 'text');
            app.Unit_label = uilabel(app.UIFigure);
            app.Unit_label.Text = '元/吨';
            app.YesBtn = uibutton(app.UIFigure, 'push');
            app.YesBtn.ButtonPushedFcn = createCallbackFcn(app, @YesBtnButtonPushed, true);
            app.YesBtn.Text = '确认';
            app.NoBtn = uibutton(app.UIFigure, 'push');
            app.NoBtn.ButtonPushedFcn = createCallbackFcn(app, @NoBtnButtonPushed, true);
            app.NoBtn.Text = '取消';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '碳捕集单价设置';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TanBuJiSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end