classdef MainForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ForecastStartBtn             matlab.ui.control.Button
        ForecastDateChoose           matlab.ui.control.DatePicker
        Label_15                     matlab.ui.control.Label
        ChuNeng_sw                   matlab.ui.control.Switch
        Label_8                      matlab.ui.control.Label
        FengDian3_sw                 matlab.ui.control.ToggleSwitch
        Label_14                     matlab.ui.control.Label
        FengDian2_sw                 matlab.ui.control.ToggleSwitch
        Label_13                     matlab.ui.control.Label
        FengDian1_sw                 matlab.ui.control.ToggleSwitch
        Label_12                     matlab.ui.control.Label
        TanBuJi_settings_label       matlab.ui.control.Label
        TanBuJi_settings             matlab.ui.control.Button
        ChuNeng_settings_label       matlab.ui.control.Label
        ChuNeng_settings             matlab.ui.control.Button
        FengDianJiZu_settings_label  matlab.ui.control.Label
        FengDianJiZu_settings        matlab.ui.control.Button
        HuoDianJiZu_settings_label   matlab.ui.control.Label
        HuoDianJiZu_settings         matlab.ui.control.Button
        HuoDian3_sw                  matlab.ui.control.ToggleSwitch
        Label_11                     matlab.ui.control.Label
        HuoDian2_sw                  matlab.ui.control.ToggleSwitch
        Label_10                     matlab.ui.control.Label
        HuoDian1_sw                  matlab.ui.control.ToggleSwitch
        Label_9                      matlab.ui.control.Label
        Total_costs                  matlab.ui.control.EditField
        Label_7                      matlab.ui.control.Label
        ChuNeng_costs                matlab.ui.control.EditField
        Label_6                      matlab.ui.control.Label
        QiFeng_costs                 matlab.ui.control.EditField
        Label_5                      matlab.ui.control.Label
        FengDian_costs               matlab.ui.control.EditField
        Label_4                      matlab.ui.control.Label
        ShiFuHe_costs                matlab.ui.control.EditField
        Label_3                      matlab.ui.control.Label
        TanBuJi_costs                matlab.ui.control.EditField
        Label_2                      matlab.ui.control.Label
        HuoDian_costs                matlab.ui.control.EditField
        Label                        matlab.ui.control.Label
        CurrentGraphBtn              matlab.ui.control.Button
    end

    
    properties (Access = private)
        huoDianJiZuSeetings % Description
        fengDianJiZuSeetings % Description
        chuNengSettings % Description
        currentGraph % Description
        tanBuJiSettings % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CurrentGraphBtn
        function CurrentGraphBtnPushed(app, event)
            app.CurrentGraphBtn.Enable = 'off';

            app.currentGraph = CurrentGraphForm(app);
        end

        % Button pushed function: HuoDianJiZu_settings
        function HuoDianJiZu_settingsPushed(app, event)
            app.HuoDianJiZu_settings.Enable = 'off';

            app.huoDianJiZuSeetings = HuoDianJiZuSettings(app);
        end

        % Button pushed function: FengDianJiZu_settings
        function FengDianJiZu_settingsButtonPushed(app, event)
            app.FengDianJiZu_settings.Enable = 'off';

            app.fengDianJiZuSeetings = FengDianJiZuSettings(app);
        end

        % Button pushed function: ChuNeng_settings
        function ChuNeng_settingsButtonPushed(app, event)
            app.ChuNeng_settings.Enable = 'off';

            app.chuNengSettings = ChuNengSettings(app);
        end

        % Button pushed function: TanBuJi_settings
        function TanBuJi_settingsButtonPushed(app, event)
            app.TanBuJi_settings.Enable = 'off';

            app.tanBuJiSettings = TanBuJiSettings(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CurrentGraphBtn = uibutton(app.UIFigure, 'push');
            app.CurrentGraphBtn.ButtonPushedFcn = createCallbackFcn(app, @CurrentGraphBtnPushed, true);
            app.CurrentGraphBtn.Text = '机组实时发电';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '火电成本';
            app.HuoDian_costs = uieditfield(app.UIFigure, 'text');
            app.HuoDian_costs.Editable = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '碳捕集成本';
            app.TanBuJi_costs = uieditfield(app.UIFigure, 'text');
            app.TanBuJi_costs.Editable = 'off';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '失负荷成本';
            app.ShiFuHe_costs = uieditfield(app.UIFigure, 'text');
            app.ShiFuHe_costs.Editable = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '风电成本';
            app.FengDian_costs = uieditfield(app.UIFigure, 'text');
            app.FengDian_costs.Editable = 'off';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '弃风成本';
            app.QiFeng_costs = uieditfield(app.UIFigure, 'text');
            app.QiFeng_costs.Editable = 'off';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '储能成本';
            app.ChuNeng_costs = uieditfield(app.UIFigure, 'text');
            app.ChuNeng_costs.Editable = 'off';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '总成本';
            app.Total_costs = uieditfield(app.UIFigure, 'text');
            app.Total_costs.Editable = 'off';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '火电机组1';
            app.HuoDian1_sw = uiswitch(app.UIFigure, 'toggle');
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '火电机组2';
            app.HuoDian2_sw = uiswitch(app.UIFigure, 'toggle');
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '火电机组3';
            app.HuoDian3_sw = uiswitch(app.UIFigure, 'toggle');
            app.HuoDianJiZu_settings = uibutton(app.UIFigure, 'push');
            app.HuoDianJiZu_settings.ButtonPushedFcn = createCallbackFcn(app, @HuoDianJiZu_settingsPushed, true);
            app.HuoDianJiZu_settings.Icon = fullfile(pathToMLAPP, 'R-C.jpg');
            app.HuoDianJiZu_settings.Text = '';
            app.HuoDianJiZu_settings_label = uilabel(app.UIFigure);
            app.HuoDianJiZu_settings_label.Text = '火电机组参数设置';
            app.FengDianJiZu_settings = uibutton(app.UIFigure, 'push');
            app.FengDianJiZu_settings.ButtonPushedFcn = createCallbackFcn(app, @FengDianJiZu_settingsButtonPushed, true);
            app.FengDianJiZu_settings.Icon = fullfile(pathToMLAPP, 'R-C.jpg');
            app.FengDianJiZu_settings.Text = '';
            app.FengDianJiZu_settings_label = uilabel(app.UIFigure);
            app.FengDianJiZu_settings_label.Text = '风电机组参数设置';
            app.ChuNeng_settings = uibutton(app.UIFigure, 'push');
            app.ChuNeng_settings.ButtonPushedFcn = createCallbackFcn(app, @ChuNeng_settingsButtonPushed, true);
            app.ChuNeng_settings.Icon = fullfile(pathToMLAPP, 'R-C.jpg');
            app.ChuNeng_settings.Text = '';
            app.ChuNeng_settings_label = uilabel(app.UIFigure);
            app.ChuNeng_settings_label.Text = '储能参数设置';
            app.TanBuJi_settings = uibutton(app.UIFigure, 'push');
            app.TanBuJi_settings.ButtonPushedFcn = createCallbackFcn(app, @TanBuJi_settingsButtonPushed, true);
            app.TanBuJi_settings.Icon = fullfile(pathToMLAPP, 'R-C.jpg');
            app.TanBuJi_settings.Text = '';
            app.TanBuJi_settings_label = uilabel(app.UIFigure);
            app.TanBuJi_settings_label.Text = '碳捕集成本设置';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '风电机组1';
            app.FengDian1_sw = uiswitch(app.UIFigure, 'toggle');
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '风电机组2';
            app.FengDian2_sw = uiswitch(app.UIFigure, 'toggle');
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '风电机组3';
            app.FengDian3_sw = uiswitch(app.UIFigure, 'toggle');
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '储能';
            app.ChuNeng_sw = uiswitch(app.UIFigure, 'slider');
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '预测日期';
            app.ForecastDateChoose = uidatepicker(app.UIFigure);
            app.ForecastStartBtn = uibutton(app.UIFigure, 'push');
            app.ForecastStartBtn.Text = '开始预测';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end